classdef polakovicMatlab < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        hlavneOkno                  matlab.ui.Figure
        ProgramMenu                 matlab.ui.container.Menu
        PoiatonstavMenu             matlab.ui.container.Menu
        UkoniAltF4Menu              matlab.ui.container.Menu
        PomocnkMenu                 matlab.ui.container.Menu
        SprievodcaMenu              matlab.ui.container.Menu
        OprogrameMenu               matlab.ui.container.Menu
        RieeniehlavolamuPanel       matlab.ui.container.Panel
        RiesButton                  matlab.ui.control.Button
        VbermetdyrieeniaLabel       matlab.ui.control.Label
        metodyComboBox              matlab.ui.control.DropDown
        asrieeniaEditFieldLabel     matlab.ui.control.Label
        EditFieldCas                matlab.ui.control.EditField
        PoetkrokovLabel             matlab.ui.control.Label
        EditFieldKroky              matlab.ui.control.EditField
        stopButton                  matlab.ui.control.Button
        NataniepoiatonhostavuPanel  matlab.ui.container.Panel
        NacitButton                 matlab.ui.control.Button
        UIAxes11                    matlab.ui.control.UIAxes
        PostupnataniaLabel          matlab.ui.control.Label
        UIAxes2                     matlab.ui.control.UIAxes
    end


    properties (Access = private)

        
        casovac;
        krokovac;
        maticaKocky = zeros(6,9);
        pritomnostKocky;
        chyba;


    end

    methods (Access = private)
    
        function otocPlatformu45 (app)
            ruka = NXTMotor('B', 'Power', -30);
            ruka.TachoLimit = 48;
            ruka.SendToNXT();
        ruka.WaitFor;
        
        end
        
        function otocPlatfortmu45NN (app)           % uplna negacia 45 stupnoveho tocenia
            ruka = NXTMotor('B', 'Power', 30);
            ruka.TachoLimit = 148;
            ruka.SendToNXT();
        ruka.WaitFor;
        
        end
        
        function otocPlatformu90 (app)
            ruka = NXTMotor('B', 'Power', -50);
            ruka.TachoLimit = 92;
            ruka.SendToNXT();
        ruka.WaitFor;
        
        end
        
        function otocPlatformu90N (app)
            ruka = NXTMotor('B', 'Power', 50);
            ruka.TachoLimit = 92;
            ruka.SendToNXT();
        ruka.WaitFor;
        
        end
        
        function otocPlatformu90NN (app)           % upla negacia 90 stupnoveho tocenia
            ruka = NXTMotor('B', 'Power', 50);
            ruka.TachoLimit = 270;
            ruka.SendToNXT();
        ruka.WaitFor;
        
        end
        
        function otocSenzorA1 (app)
            ruka = NXTMotor('A', 'Power', 20);
            ruka.TachoLimit = 78;
            ruka.SendToNXT();
        ruka.WaitFor;
        
        end
        
        function otocSenzorA2 (app)
            ruka = NXTMotor('A', 'Power', 10);
            ruka.TachoLimit = 24;
            ruka.SendToNXT();
        ruka.WaitFor;
        
        end
        
        function otocSenzorA3 (app)
            ruka = NXTMotor('A', 'Power', 10);
            ruka.TachoLimit = 19;
            ruka.SendToNXT();
        ruka.WaitFor;
        
        end
        
        function otocSenzorA4 (app)
            ruka = NXTMotor('A', 'Power', -10);
            ruka.TachoLimit = 43;
            ruka.SendToNXT();
        ruka.WaitFor;
        end
        
        
        function otocSenzorA5 (app)
            ruka = NXTMotor('A', 'Power', 10);
            ruka.TachoLimit = 43;
            ruka.SendToNXT();
        ruka.WaitFor;
        
        end
        
        function otocSenzorB1 (app)
            ruka = NXTMotor('A', 'Power', 30);
            ruka.TachoLimit = 10;
            ruka.SendToNXT();
        ruka.WaitFor;
        
        end
        
        function otocSenzorBN (app)
            ruka = NXTMotor('A', 'Power', -40);
            ruka.TachoLimit = 128;
            ruka.SendToNXT();
        ruka.WaitFor;
        
        end
        
        % ---------------------------------------- pretocenie kocky
        function otocKocku (app)
            ruka = NXTMotor('C', 'Power', 70);
            ruka.TachoLimit = +65;
            ruka.SendToNXT();
        ruka.WaitFor;
        
            ruka = NXTMotor('C', 'Power', -50);
            ruka.TachoLimit = +60;
            ruka.SendToNXT();
        ruka.WaitFor;
            
        end
        % --------------------------------------- pretocenie kocky


        function [farba] = nacitajFarbu (app)
    
                  port = SENSOR_1;              
                  OpenNXT2Color(port, 'RED'); 
                  RGB(1,1) = GetNXT2Color(port);
                  CloseSensor(port);            
                               
                  OpenNXT2Color(port, 'GREEN'); 
                  RGB(1,2) = GetNXT2Color(port);
                  CloseSensor(port);            
                                
                  OpenNXT2Color(port, 'BLUE'); 
                  RGB(1,3) = GetNXT2Color(port);
                  CloseSensor(port);  
                  
                  farba = 'U'; %neznama
                  
                  % kalibracia
                  %             minimum                  maximum
                  if     RGB(1,1) > 150     &&    RGB(1,1) < 305 ...
                     &&  RGB(1,2) > 185     &&    RGB(1,2) < 345 ...
                     &&  RGB(1,3) > 110     &&    RGB(1,3) < 235
                    farba = 'G'; %zelena
                  end
                  
                  %             minimum                  maximum
                  if     RGB(1,1) > 440     &&    RGB(1,1) < 535 ...
                     &&  RGB(1,2) > 300     &&    RGB(1,2) < 415 ...
                     &&  RGB(1,3) > 180     &&    RGB(1,3) < 305
                    farba = 'Y'; %zlta
                  end
                  
                  %             minimum                  maximum
                  if     RGB(1,1) > 360     &&    RGB(1,1) < 495 ...
                     &&  RGB(1,2) > 280     &&    RGB(1,2) < 450 ...
                     &&  RGB(1,3) > 265     &&    RGB(1,3) < 410
                    farba = 'W'; %biela
                  end
                  
                  %             minimum                  maximum
                  if     RGB(1,1) > 145     &&    RGB(1,1) < 275 ...
                     &&  RGB(1,2) > 125     &&    RGB(1,2) < 250 ...
                     &&  RGB(1,3) > 195     &&    RGB(1,3) < 300
                    farba = 'B'; %modra
                  end
                  
                  %             minimum                  maximum
                  if     RGB(1,1) > 300     &&    RGB(1,1) < 480 ...
                     &&  RGB(1,2) > 70      &&    RGB(1,2) < 220 ...
                     &&  RGB(1,3) > 30      &&    RGB(1,3) < 160
                    farba = 'R'; %cervena
                  end
                  
                  %             minimum                  maximum
                  if     RGB(1,1) > 465     &&    RGB(1,1) < 540 ...
                     &&  RGB(1,2) > 180     &&    RGB(1,2) < 265 ...
                     &&  RGB(1,3) > 78      &&    RGB(1,3) < 160
                    farba = 'O'; %oranzova
                  end
          
        end
        
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function Obrazky(app)
            
            COM_CloseNXT all;
            
            handle = COM_OpenNXT();
            NXT_PlayTone(400,400, handle);
            pause(.3);
            NXT_PlayTone(800,500, handle);
            pause(.1);
            COM_CloseNXT all
            
            h = COM_OpenNXT();
            COM_SetDefaultNXT(h);
            
            % graf na proces nacitania
            title(app.UIAxes11, []);
            xlabel(app.UIAxes11, []);
            ylabel(app.UIAxes11, []);
            app.UIAxes11.XAxis.TickLabels = {};
            app.UIAxes11.YAxis.TickLabels = {};     

            
            % obrazok - Logo MTF STU
            title(app.UIAxes2, []);
            xlabel(app.UIAxes2, []);
            ylabel(app.UIAxes2, []);
            app.UIAxes2.XAxis.TickLabels = {};
            app.UIAxes2.XAxis.TickLabels = {};
            
            I = imshow('b.jpg', 'Parent', app.UIAxes2);
            
            
            % pociatocny stav tlacitok, casomiery a krokomeru
            
            app.RiesButton.Enable = 'off';
            app.EditFieldCas.Value = datestr(seconds(0), 'HH:MM:SS');
            app.EditFieldKroky.Value = num2str(0);


        end

        % Button pushed function: RiesButton
        function tlacitkoRies(app, event)
            % Tlacitko Riesenie
            % zahaji casovac
            % nastavi premenne casovac a krokovac na 0
            % vypne tlacitka ries aj nacitaj 
            %       - aby sa nedal proces riesenia prerusit
            % casovac bezi 13 hodin, alebo dokym mu nepride podnet ze preslo 13 hodin
            %       - tento pokyn prichadza v premennej app.casovac            
            
            tic;
            app.casovac = 0;
            app.krokovac = 0;
 
            app.NacitButton.Enable = 'off';
            app.RiesButton.Enable = 'off';
            
                while app.casovac<50000
                pause(1);
                toc;         
                casRiesenia = toc;
                app.EditFieldCas.Value = datestr(seconds(casRiesenia), 'HH:MM:SS');
                app.casovac = app.casovac + 1;
                app.EditFieldKroky.Value = num2str(app.krokovac);
                end
            
            % kazdu sekundu kontroluje ci sa zvysil pocet krokov
            % cyklus robi pauzu po jednej sekunde a pripocitava cas
            % po stlaceni "ries!" sa znova vynuluju kroky aj cas


            % TUTO CASE PODMIENKA


            % NA KONCI RIESENIA VYMAZ KOMENTAR
            % app.casovac = 50000

        end

        % Button pushed function: NacitButton
        function tlacitkoNacitaj(app, event)
            % Tlacitko Nacitaj vstupne udaje
            % vypne tlacitko ries aby sa nedali spustit ries a nacitaj sucasne
            % vynuluje casomieru
            % vynuluje krokomer
            
            COM_CloseNXT all;
            h = COM_OpenNXT();
            COM_SetDefaultNXT(h);
            
            % a na konci spristupni tlacitko ries                    
                OpenUltrasonic(SENSOR_2);
                app.pritomnostKocky = GetUltrasonic(SENSOR_2);
                CloseSensor(SENSOR_2);
            
            if app.pritomnostKocky > 15
                opts = struct('WindowStyle','modal','Interpreter','tex');
                app.chyba = warndlg('Rubikova kocka nie je prítomná.','Chyba',opts);
                clearvars app.pritomnostKocky;
                
            else
            app.NacitButton.Enable = 'off';
            app.RiesButton.Enable = 'off';
            app.EditFieldCas.Value = datestr(seconds(0), 'HH:MM:SS');
            app.EditFieldKroky.Value = num2str(0);
            
            
            % --------------- tuto vyriesit status bar
            x = [1,0];
            y = [1,1];
            plot(app.UIAxes11,x,y,'linewidth', 20);
            % --------------- tuto vyriesit status bar
            
            
                for stena = 1:4
                    
                      app.otocPlatformu45; % -------------- pohyb platforma
           app.otocSenzorA1;   app.nacitajFarbu; app.maticaKocky(stena,1) = app.nacitajFarbu;
           app.otocSenzorA2;   app.nacitajFarbu; app.maticaKocky(stena,2) = app.nacitajFarbu;       
           app.otocSenzorA3;   app.nacitajFarbu; app.maticaKocky(stena,3) = app.nacitajFarbu;       
           app.otocSenzorA4;               
           app.otocPlatformu90; % --------------- pohyb platforma     
                               app.nacitajFarbu; app.maticaKocky(stena,4) = app.nacitajFarbu;                       
           app.otocSenzorA5;   app.nacitajFarbu; app.maticaKocky(stena,5) = app.nacitajFarbu;        
           app.otocPlatfortmu45NN; % ------------ pohyb platforma        
           app.otocSenzorB1;   app.nacitajFarbu; app.maticaKocky(stena,6) = app.nacitajFarbu;        
           app.otocPlatformu90; % --------------- pohyb platforma      
                               app.nacitajFarbu; app.maticaKocky(stena,7) = app.nacitajFarbu;      
           app.otocPlatformu90; % --------------- pohyb platforma        
                               app.nacitajFarbu; app.maticaKocky(stena,8) = app.nacitajFarbu;
           app.otocPlatformu90; % --------------- pohyb platforma                        
                               app.nacitajFarbu; app.maticaKocky(stena,9) = app.nacitajFarbu;
                
                       app.otocSenzorBN;
                       app.otocPlatformu90NN;  
                       app.otocKocku; 

                end 
    
                       app.otocPlatformu90;
                       app.otocKocku;
                       app.otocPlatformu90N;
                
                stena = 5;
    
                       app.otocPlatformu45; % -------------- pohyb platforma
           app.otocSenzorA1;   app.nacitajFarbu; app.maticaKocky(stena,1) = app.nacitajFarbu;
           app.otocSenzorA2;   app.nacitajFarbu; app.maticaKocky(stena,2) = app.nacitajFarbu;       
           app.otocSenzorA3;   app.nacitajFarbu; app.maticaKocky(stena,3) = app.nacitajFarbu;       
           app.otocSenzorA4;               
           app.otocPlatformu90; % --------------- pohyb platforma     
                               app.nacitajFarbu; app.maticaKocky(stena,4) = app.nacitajFarbu;                       
           app.otocSenzorA5;   app.nacitajFarbu; app.maticaKocky(stena,5) = app.nacitajFarbu;        
           app.otocPlatfortmu45NN; % ------------ pohyb platforma        
           app.otocSenzorB1;   app.nacitajFarbu; app.maticaKocky(stena,6) = app.nacitajFarbu;        
           app.otocPlatformu90; % --------------- pohyb platforma      
                               app.nacitajFarbu; app.maticaKocky(stena,7) = app.nacitajFarbu;      
           app.otocPlatformu90; % --------------- pohyb platforma        
                               app.nacitajFarbu; app.maticaKocky(stena,8) = app.nacitajFarbu;
           app.otocPlatformu90; % --------------- pohyb platforma                        
                               app.nacitajFarbu; app.maticaKocky(stena,9) = app.nacitajFarbu;
               
                       app.otocSenzorBN;
                       app.otocPlatformu90NN;  
                       app.otocKocku;
                       app.otocKocku;
       
                stena = 6;
    
                       app.otocPlatformu45; % -------------- pohyb platforma
           app.otocSenzorA1;   app.nacitajFarbu; app.maticaKocky(stena,1) = app.nacitajFarbu;
           app.otocSenzorA2;   app.nacitajFarbu; app.maticaKocky(stena,2) = app.nacitajFarbu;       
           app.otocSenzorA3;   app.nacitajFarbu; app.maticaKocky(stena,3) = app.nacitajFarbu;       
           app.otocSenzorA4;               
           app.otocPlatformu90; % --------------- pohyb platforma     
                               app.nacitajFarbu; app.maticaKocky(stena,4) = app.nacitajFarbu;                       
           app.otocSenzorA5;   app.nacitajFarbu; app.maticaKocky(stena,5) = app.nacitajFarbu;        
           app.otocPlatfortmu45NN; % ------------ pohyb platforma        
           app.otocSenzorB1;   app.nacitajFarbu; app.maticaKocky(stena,6) = app.nacitajFarbu;        
           app.otocPlatformu90; % --------------- pohyb platforma      
                               app.nacitajFarbu; app.maticaKocky(stena,7) = app.nacitajFarbu;      
           app.otocPlatformu90; % --------------- pohyb platforma        
                               app.nacitajFarbu; app.maticaKocky(stena,8) = app.nacitajFarbu;
           app.otocPlatformu90; % --------------- pohyb platforma                        
                               app.nacitajFarbu; app.maticaKocky(stena,9) = app.nacitajFarbu;
                
                       app.otocSenzorBN;
                       app.otocPlatformu90NN;  
                       
           obsahuje = ismember(['U'],app.maticaKocky);      % pocita Ucka

                    if obsahuje == 1            % ked obsahuje aspon jedno U
                        pocetChyb = sum(app.maticaKocky(:) == 'U');
                        pocetW = sum(app.maticaKocky(:) == 'W');
                        pocetY = sum(app.maticaKocky(:) == 'Y');
                        pocetG = sum(app.maticaKocky(:) == 'G');
                        pocetB = sum(app.maticaKocky(:) == 'B');
                        pocetR = sum(app.maticaKocky(:) == 'R');
                        pocetO = sum(app.maticaKocky(:) == 'O');
                        disp(pocetChyb);
        
                        if pocetChyb > 4         % ked je viac ako 4 U v matici
                            
                            clear app.maticaKocky; % vymaze maticu
                            opts = struct('WindowStyle','modal','Interpreter','tex');
                            app.chyba = warndlg('Načítavanie počiatočného stavu sa nepodarilo!',...
                                    'Chyba',opts);
                            app.NacitButton.Enable = 'on';
                            app.RiesButton.Enable = 'off';
                            
                        elseif   pocetW == 9 && ...
                                 pocetG == 9 && ...
                                 pocetO == 9 && ...
                                 pocetR == 9 && ...
                                 pocetY == 9       
                                 
                        app.maticaKocky(app.maticaKocky == 'U') = 'B';
                        app.NacitButton.Enable = 'on';
                        app.RiesButton.Enable = 'on';
                        opts = struct('WindowStyle','modal','Interpreter','tex');
                        app.chyba = warndlg('Načítavanie prebehlo v poriadku.',...
                                            'Načítanie počiatočného stavu',opts);
                                 
                        elseif   pocetB == 9 && ...
                                 pocetG == 9 && ...
                                 pocetO == 9 && ...
                                 pocetR == 9 && ...
                                 pocetY == 9       
                                 
                        app.maticaKocky(app.maticaKocky == 'U') = 'W';
                        app.NacitButton.Enable = 'on';
                        app.RiesButton.Enable = 'on';
                        
                        elseif   pocetW == 9 && ...
                                 pocetB == 9 && ...
                                 pocetO == 9 && ...
                                 pocetR == 9 && ...
                                 pocetY == 9       
                                 
                       app.maticaKocky(app.maticaKocky == 'U') = 'G';
                       app.NacitButton.Enable = 'on';
                       app.RiesButton.Enable = 'on';
                       opts = struct('WindowStyle','modal','Interpreter','tex');
                       app.chyba = warndlg('Načítavanie prebehlo v poriadku.',...
                                           'Načítanie počiatočného stavu',opts);                                 
                       
                        elseif   pocetW == 9 && ...
                                 pocetG == 9 && ...
                                 pocetB == 9 && ...
                                 pocetR == 9 && ...
                                 pocetY == 9       
                                 
                        app.maticaKocky(app.maticaKocky == 'U') = 'O';
                        app.NacitButton.Enable = 'on';
                        app.RiesButton.Enable = 'on';
                        opts = struct('WindowStyle','modal','Interpreter','tex');
                        app.chyba = warndlg('Načítavanie prebehlo v poriadku.',...
                                            'Načítanie počiatočného stavu',opts);
                        
                        elseif   pocetW == 9 && ...
                                 pocetG == 9 && ...
                                 pocetO == 9 && ...
                                 pocetB == 9 && ...
                                 pocetY == 9       
                                 
                        app.maticaKocky(app.maticaKocky == 'U') = 'R';
                        app.NacitButton.Enable = 'on';
                        app.RiesButton.Enable = 'on';
                        opts = struct('WindowStyle','modal','Interpreter','tex');
                        app.chyba = warndlg('Načítavanie prebehlo v poriadku.',...
                                            'Načítanie počiatočného stavu',opts);
                        
                        elseif   pocetW == 9 && ...
                                 pocetG == 9 && ...
                                 pocetO == 9 && ...
                                 pocetR == 9 && ...
                                 pocetB == 9       
                                 
                        app.maticaKocky(app.maticaKocky == 'U') = 'Y';
                        app.NacitButton.Enable = 'on';
                        app.RiesButton.Enable = 'on';
                        opts = struct('WindowStyle','modal','Interpreter','tex');
                        app.chyba = warndlg('Načítavanie prebehlo v poriadku.',...
                                            'Načítanie počiatočného stavu',opts);
                        
                        else
                        clear app.maticaKocky;    
                opts = struct('WindowStyle','modal','Interpreter','tex');
                app.chyba = warndlg('Načítavanie počiatočného stavu sa nepodarilo!',...
                                    'Chyba',opts);
                app.NacitButton.Enable = 'on';
                app.RiesButton.Enable = 'off';
                        
                        end % end switchu
                        
                    else
                    clear app.maticaKocky;    
           opts = struct('WindowStyle','modal','Interpreter','tex');
           app.chyba = warndlg('Načítavanie počiatočného stavu sa nepodarilo!',...
                               'Chyba',opts);
           app.NacitButton.Enable = 'on';
           app.RiesButton.Enable = 'off';                        
                        
                    end % end if pocetchyb > 4
       
            end %end if pritomnost
        end

        % Menu selected function: UkoniAltF4Menu
        function programKoniec(app, event)
            % moznost Ukoncit program
            % v rolete program, ukonci program aj pocas chodu
            COM_CloseNXT all
            handle = COM_OpenNXT();
            NXT_PlayTone(800,400, handle);
            pause(0.1);
            NXT_PlayTone(300,100, handle);
            
            COM_CloseNXT all
            close all
            app.delete;
            clearvars
        end

        % Button pushed function: stopButton
        function stopButtonPushed(app, event)
            % stop tlacitko
            % zastavi casovac, tym ze mu posle hodnotu na zastavenie cyklu
            % zastavi krokovac tym ze sa zastavil aj casovac
            % spristupni tlacitko nacitaj a schova ries
            %    - lebo najprv musi nacitat udaje
            % vynuluje nacitavaci status bar
            
            % toto tlacitko je vzdy viditelne!
            
            app.casovac = 50000;
            app.NacitButton.Enable = 'on';
            app.RiesButton.Enable = 'off';
            x = [0,0];
            y = [0,0];
            plot(app.UIAxes11,x,y,'linewidth', 20);
            COM_CloseNXT(COM_GetDefaultNXT());
            COM_CloseNXT all
            close all
        end

        % Menu selected function: OprogrameMenu
        function Oprograme(app, event)
            % moznost O programe
            % vytvori nove okno
            
            fig = uifigure('name','O programe');
            ax = uiaxes(fig,'Position',[10 10 550 400]);
            
            title(ax, []);
            xlabel(ax, []);
            ylabel(ax, []);
            ax.XAxis.TickLabels = {};
            ax.YAxis.TickLabels = {}; 
            
            I = imshow('i.jpg', 'Parent', ax);
            
            
        end

        % Menu selected function: SprievodcaMenu
        function Sprievodca(app, event)
            % moznost sprievodca
            % vytvori nove okno
            
            fig = uifigure('name','Sprievodca');
             
            ax = uiaxes(fig,'Position',[10 10 550 400]);
            
            title(ax, []);
            xlabel(ax, []);
            ylabel(ax, []);
            ax.XAxis.TickLabels = {};
            ax.YAxis.TickLabels = {}; 
            
            I = imshow('l.jpg', 'Parent', ax); 
             
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.hlavneOkno = uifigure;
            app.hlavneOkno.Name = 'Ovládanie objektu Lego Mindstorms pomocou nástroja Matlab';
            app.ProgramMenu = uimenu(app.hlavneOkno);
            app.ProgramMenu.Text = 'Program';
            app.PoiatonstavMenu = uimenu(app.ProgramMenu);
            app.PoiatonstavMenu.Enable = 'off';
            app.PoiatonstavMenu.Text = 'Počiatočný stav';
            app.UkoniAltF4Menu = uimenu(app.ProgramMenu);
            app.UkoniAltF4Menu.MenuSelectedFcn = createCallbackFcn(app, @programKoniec, true);
            app.UkoniAltF4Menu.Text = 'Ukončiť        (Alt + F4)';
            app.PomocnkMenu = uimenu(app.hlavneOkno);
            app.PomocnkMenu.Text = 'Pomocník';
            app.SprievodcaMenu = uimenu(app.PomocnkMenu);
            app.SprievodcaMenu.MenuSelectedFcn = createCallbackFcn(app, @Sprievodca, true);
            app.SprievodcaMenu.Text = 'Sprievodca';
            app.OprogrameMenu = uimenu(app.PomocnkMenu);
            app.OprogrameMenu.MenuSelectedFcn = createCallbackFcn(app, @Oprograme, true);
            app.OprogrameMenu.Text = 'O programe';
            app.RieeniehlavolamuPanel = uipanel(app.hlavneOkno);
            app.RieeniehlavolamuPanel.Title = '  Riešenie hlavolamu';
            app.RiesButton = uibutton(app.RieeniehlavolamuPanel, 'push');
            app.RiesButton.ButtonPushedFcn = createCallbackFcn(app, @tlacitkoRies, true);
            app.RiesButton.Text = 'Riešiť';
            app.VbermetdyrieeniaLabel = uilabel(app.RieeniehlavolamuPanel);
            app.VbermetdyrieeniaLabel.Text = 'Výber metódy riešenia:';
            app.metodyComboBox = uidropdown(app.RieeniehlavolamuPanel);
            app.metodyComboBox.Items = {'metóda Začiatočník', 'Fridrichova metóda'};
            app.metodyComboBox.Value = 'metóda Začiatočník';
            app.asrieeniaEditFieldLabel = uilabel(app.RieeniehlavolamuPanel);
            app.asrieeniaEditFieldLabel.Text = 'Čas riešenia';
            app.EditFieldCas = uieditfield(app.RieeniehlavolamuPanel, 'text');
            app.EditFieldCas.Editable = 'off';
            app.PoetkrokovLabel = uilabel(app.RieeniehlavolamuPanel);
            app.PoetkrokovLabel.Text = 'Počet krokov';
            app.EditFieldKroky = uieditfield(app.RieeniehlavolamuPanel, 'text');
            app.EditFieldKroky.Editable = 'off';
            app.stopButton = uibutton(app.RieeniehlavolamuPanel, 'push');
            app.stopButton.ButtonPushedFcn = createCallbackFcn(app, @stopButtonPushed, true);
            app.stopButton.Text = 'Stop';
            app.NataniepoiatonhostavuPanel = uipanel(app.hlavneOkno);
            app.NataniepoiatonhostavuPanel.Title = '  Načítanie počiatočného stavu';
            app.NacitButton = uibutton(app.NataniepoiatonhostavuPanel, 'push');
            app.NacitButton.ButtonPushedFcn = createCallbackFcn(app, @tlacitkoNacitaj, true);
            app.NacitButton.Text = 'Načítať';
            app.UIAxes11 = uiaxes(app.NataniepoiatonhostavuPanel);
            title(app.UIAxes11, 'Title')
            xlabel(app.UIAxes11, 'X')
            ylabel(app.UIAxes11, 'Y')
            app.PostupnataniaLabel = uilabel(app.NataniepoiatonhostavuPanel);
            app.PostupnataniaLabel.Text = 'Postup načítania:';
            app.UIAxes2 = uiaxes(app.hlavneOkno);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
        end
    end

    methods (Access = public)

        % Construct app
        function app = polakovicMatlab

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.hlavneOkno)

            % Execute the startup function
            runStartupFcn(app, @Obrazky)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.hlavneOkno)
        end
    end
end